classdef double_elevator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        CabinAPanel                     matlab.ui.container.Panel
        goto1A                          matlab.ui.control.Button
        goto2A                          matlab.ui.control.Button
        goto4A                          matlab.ui.control.Button
        goto5A                          matlab.ui.control.Button
        closeDoorA                      matlab.ui.control.Button
        goto3A                          matlab.ui.control.Button
        openDoorA                       matlab.ui.control.Button
        emergencyA                      matlab.ui.control.Button
        goto6A                          matlab.ui.control.Button
        isMovingLampLabel_2             matlab.ui.control.Label
        isMovingLampA                   matlab.ui.control.Lamp
        Floor5Panel                     matlab.ui.container.Panel
        Down5Button                     matlab.ui.control.Button
        LampFloor5                      matlab.ui.control.Lamp
        Up5Button                       matlab.ui.control.Button
        Floor4Panel                     matlab.ui.container.Panel
        Down4Button                     matlab.ui.control.Button
        Up4Button                       matlab.ui.control.Button
        LampFloor4                      matlab.ui.control.Lamp
        Floor2Panel                     matlab.ui.container.Panel
        Down2Button                     matlab.ui.control.Button
        Up2Button                       matlab.ui.control.Button
        LampFloor2                      matlab.ui.control.Lamp
        Floor1Panel                     matlab.ui.container.Panel
        Up1Button                       matlab.ui.control.Button
        LampFloor1                      matlab.ui.control.Lamp
        Floor3Panel                     matlab.ui.container.Panel
        Down3Button                     matlab.ui.control.Button
        Up3Button                       matlab.ui.control.Button
        LampFloor3                      matlab.ui.control.Lamp
        elevatorIndicatorPanel          matlab.ui.container.Panel
        DoorstateEditFieldLabel         matlab.ui.control.Label
        DoorStateEditField              matlab.ui.control.EditField
        ElevatorstateEditFieldLabel     matlab.ui.control.Label
        ElevatorStateEditField          matlab.ui.control.EditField
        ListcommandEditFieldLabel       matlab.ui.control.Label
        ListCommandEditField            matlab.ui.control.EditField
        CurrentdirectionEditFieldLabel  matlab.ui.control.Label
        CurrentDirectionEditField       matlab.ui.control.EditField
        ResetButton                     matlab.ui.control.Button
        CabinBPanel                     matlab.ui.container.Panel
        goto1B                          matlab.ui.control.Button
        goto2B                          matlab.ui.control.Button
        goto4B                          matlab.ui.control.Button
        goto5B                          matlab.ui.control.Button
        closeDoorB                      matlab.ui.control.Button
        goto3B                          matlab.ui.control.Button
        openDoorB                       matlab.ui.control.Button
        emergencyB                      matlab.ui.control.Button
        goto6B                          matlab.ui.control.Button
        isMovingLampLabel               matlab.ui.control.Label
        isMovingLampB                   matlab.ui.control.Lamp
        Floor6Panel                     matlab.ui.container.Panel
        Down6Button                     matlab.ui.control.Button
        LampFloor6                      matlab.ui.control.Lamp
        CabinIndicatorPanel             matlab.ui.container.Panel
        doorALabel                      matlab.ui.control.Label
        doorIsOpeningLampA              matlab.ui.control.Lamp
        doorBLabel                      matlab.ui.control.Label
        doorIsOpeningLampB              matlab.ui.control.Lamp
        TimeAEditFieldLabel             matlab.ui.control.Label
        TimeAEditField                  matlab.ui.control.EditField
        TimeBEditFieldLabel             matlab.ui.control.Label
        TimeBEditField                  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        % Cabin
        A
        B
        
        dispTimer % Use this timer to display the evevator
        data % A property to store all data
        
        
        % Store the current queue command, contain "floorCommand" and corresponding "direction"
        queue = struct('allQueue',[],'allDirection',[])
        
        % Two condition of the max min floor, current implementation is 5 floors
        maxFloor = 6;
        minFloor = 1;
        
        % Button background color
        onButtonColor = [0 1 0];
        offButtonColor = [0.8 0.8 0.8];
        
        % Lamp color
        onLampColor = [0 1 0];
        offLampColor = [1 0 0];
        
        nextLowerCabinPosition
        nextUppererCabinPosition
        
        
        % Some constant for visualization
        velDoor = 1;
        velCabin = 5;
        floorPosition = [10 110 210 310 410 510]';
        
        backGround = cell(7+3,1) % Use fill function to generate background
        door = struct('left_doorA',[],'right_doorA',[],'left_doorB',[],'right_doorB',[]);
        
        dt = 1;
        timerIndex = 1;
    end
    
    methods (Access = public)
        
        
    end
    
    methods (Access = private)
        function app = moveElevator(app,~,~)
            % The main function of the timer, also main function of the elevator
            % Read the position
            currentCabinAPosition = app.door.left_doorA.Vertices(2,2);
            currentDoorAPosition = app.door.left_doorA.Vertices(2,1);
            
            % Add the queue
            app.asignQueue;
            % Visualize the queue
            app.ListCommandEditField.Value = num2str(app.A.queue.floorRequest');
            app.CurrentDirectionEditField.Value = num2str(app.A.queue.direction');
            
            app.A = app.A.visualize(currentCabinAPosition,currentDoorAPosition);
            
            app.ElevatorStateEditField.Value = [app.A.cabinState ', ' app.B.cabinState];
            app.DoorStateEditField.Value = [app.A.doorState ', ' app.A.doorState];
            app.door.right_doorA.Vertices(:,2) = app.door.right_doorA.Vertices(:,2) + app.A.cabinSpeed*app.velCabin;
            app.door.left_doorA.Vertices(:,2) = app.door.left_doorA.Vertices(:,2) + app.A.cabinSpeed*app.velCabin;
            app.door.right_doorA.Vertices([1 4],1) = app.door.right_doorA.Vertices([1 4],1) - app.A.doorSpeed*app.velDoor;
            app.door.left_doorA.Vertices(2:3,1) = app.door.left_doorA.Vertices(2:3,1) + app.A.doorSpeed*app.velDoor;
            
            app.onOffLamp;
            app.onOffButton;
            
            app.TimeAEditField.Value = num2str(app.A.totalDoorOpenTime*app.dispTimer.Period);
            app.TimeBEditField.Value = num2str(app.B.totalDoorOpenTime*app.dispTimer.Period);
        end
        
        function app = asignQueue(app)
            % The fuction to assign the queue to the queueA or queueB
            if ~isempty(app.queue.allQueue)
                app.A = app.A.changeProperty('queue',[app.queue.allQueue app.queue.allDirection]);
                app.queue.allQueue = [];
                app.queue.allDirection = [];
            end
        end
        
        function onOffLamp(app)
            %onOffLamp to on/off lamp
            if app.A.cabinIsMoving && app.isMovingLampA.Color(2) ~= app.onLampColor(2)
                app.isMovingLampA.Color = app.onLampColor;
            elseif ~app.A.cabinIsMoving && app.isMovingLampA.Color(2) ~= app.offLampColor(2)
                app.isMovingLampA.Color = app.offLampColor;
            end
            
            if app.A.doorIsOpening && app.doorIsOpeningLampA.Color(2) ~= app.onLampColor(2)
                app.doorIsOpeningLampA.Color = app.onLampColor;
            elseif ~app.A.doorIsOpening && app.doorIsOpeningLampA.Color(2) ~= app.offLampColor(2)
                app.doorIsOpeningLampA.Color = app.offLampColor;
            end
            
            if app.B.cabinIsMoving && app.isMovingLampB.Color(2) ~= app.onLampColor(2)
                app.isMovingLampB.Color = app.onLampColor;
            elseif ~app.B.cabinIsMoving && app.isMovingLampB.Color(2) ~= app.offLampColor(2)
                app.isMovingLampB.Color = app.offLampColor;
            end
            
            if app.B.doorIsOpening && app.doorIsOpeningLampB.Color(2) ~= app.onLampColor(2)
                app.doorIsOpeningLampB.Color = app.onLampColor;
            elseif ~app.B.doorIsOpening && app.doorIsOpeningLampB.Color(2) ~= app.offLampColor(2)
                app.doorIsOpeningLampB.Color = app.offLampColor;
            end
            
            if app.A.currentCabinFloor == 6 && app.LampFloor6.Color(2) ~= app.onLampColor(2)
                app.LampFloor6.Color = app.onLampColor;
            elseif app.A.currentCabinFloor ~= 6 && app.LampFloor6.Color(2) ~= app.offLampColor(2)
                app.LampFloor6.Color = app.offLampColor;
            end
            
            if app.A.currentCabinFloor == 5 && app.LampFloor5.Color(2) ~= app.onLampColor(2)
                app.LampFloor5.Color = app.onLampColor;
            elseif app.A.currentCabinFloor ~= 5 && app.LampFloor5.Color(2) ~= app.offLampColor(2)
                app.LampFloor5.Color = app.offLampColor;
            end
            
            if app.A.currentCabinFloor == 4 && app.LampFloor4.Color(2) ~= app.onLampColor(2)
                app.LampFloor4.Color = app.onLampColor;
            elseif app.A.currentCabinFloor ~= 4 && app.LampFloor4.Color(2) ~= app.offLampColor(2)
                app.LampFloor4.Color = app.offLampColor;
            end
            
            if app.A.currentCabinFloor == 3 && app.LampFloor3.Color(2) ~= app.onLampColor(2)
                app.LampFloor3.Color = app.onLampColor;
            elseif app.A.currentCabinFloor ~= 3 && app.LampFloor3.Color(2) ~= app.offLampColor(2)
                app.LampFloor3.Color = app.offLampColor;
            end
            
            if app.A.currentCabinFloor == 2 && app.LampFloor2.Color(2) ~= app.onLampColor(2)
                app.LampFloor2.Color = app.onLampColor;
            elseif app.A.currentCabinFloor ~= 2 && app.LampFloor2.Color(2) ~= app.offLampColor(2)
                app.LampFloor2.Color = app.offLampColor;
            end
            
            if app.A.currentCabinFloor == 1 && app.LampFloor1.Color(2) ~= app.onLampColor(2)
                app.LampFloor1.Color = app.onLampColor;
            elseif app.A.currentCabinFloor ~= 1 && app.LampFloor1.Color(2) ~= app.offLampColor(2)
                app.LampFloor1.Color = app.offLampColor;
            end
            
        end
    
        function onOffButton(app)
            %onOffButton to on off button when the cabin reaches any floor, we off button when the cabinState is idle or door
            if contains(app.A.cabinState,{'idle','door'})
                switch(app.A.currentCabinFloor)
                    case 1
                        if app.goto1A.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.goto1A.BackgroundColor = app.offButtonColor;
                        end
                        if app.Up1Button.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.Up1Button.BackgroundColor = app.offButtonColor;
                        end
                    case 2
                        if app.goto2A.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.goto2A.BackgroundColor = app.offButtonColor;
                        end
                        if (app.A.cabinIsUp||app.A.nextCabinFloor == app.A.currentCabinFloor) && app.Up2Button.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.Up2Button.BackgroundColor = app.offButtonColor;
                        end
                        if (~app.A.cabinIsUp||app.A.nextCabinFloor == app.A.currentCabinFloor) && app.Down2Button.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.Down2Button.BackgroundColor = app.offButtonColor;
                        end
                    case 3
                        if app.goto3A.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.goto3A.BackgroundColor = app.offButtonColor;
                        end
                        if (app.A.cabinIsUp||app.A.nextCabinFloor == app.A.currentCabinFloor) && app.Up3Button.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.Up3Button.BackgroundColor = app.offButtonColor;
                        end
                        if (~app.A.cabinIsUp||app.A.nextCabinFloor == app.A.currentCabinFloor) && app.Down3Button.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.Down3Button.BackgroundColor = app.offButtonColor;
                        end
                    case 4
                        if app.goto4A.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.goto4A.BackgroundColor = app.offButtonColor;
                        end
                        if (app.A.cabinIsUp||app.A.nextCabinFloor == app.A.currentCabinFloor) && app.Up4Button.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.Up4Button.BackgroundColor = app.offButtonColor;
                        end
                        if (~app.A.cabinIsUp||app.A.nextCabinFloor == app.A.currentCabinFloor) && app.Down4Button.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.Down4Button.BackgroundColor = app.offButtonColor;
                        end
                    case 5
                        if app.goto5A.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.goto5A.BackgroundColor = app.offButtonColor;
                        end
                        if (app.A.cabinIsUp&&app.A.nextCabinFloor == app.A.currentCabinFloor) && app.Up5Button.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.Up5Button.BackgroundColor = app.offButtonColor;
                        end
                        if (~app.A.cabinIsUp&&app.A.nextCabinFloor == app.A.currentCabinFloor) && app.Down5Button.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.Down5Button.BackgroundColor = app.offButtonColor;
                        end
                    case 6
                        if app.Down6Button.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.Down6Button.BackgroundColor = app.offButtonColor;
                        end
                        if app.goto6A.BackgroundColor(2) ~= app.offButtonColor(2)
                            app.goto6A.BackgroundColor = app.offButtonColor;
                        end
                end
            end
            
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Delete the previous timer
            delete(timerfind)
            
            app.A = cabinElevator;
            app.B = cabinElevator;
            
            app.UIFigure.Position = [100 100 1200 800];
            
            % Create a new timer for new run
            app.dispTimer = timer('Period',0.03,...
                'ExecutionMode', 'fixedRate', ...
                'TasksToExecute', Inf);
            app.dispTimer.TimerFcn = {@app.moveElevator};
            
            % Generate random initial floor of two cabins
            cabinAInit = randi(6)-1;
            cabinBInit = randi(6)-1;
            
            app.door.left_doorA = fill(app.UIAxes,[2 25.5 25.5 2],[10 10 100 100]+100*cabinAInit,'g');
            hold(app.UIAxes)
            app.door.right_doorA = fill(app.UIAxes,[25.5 49 49 25.5],[10 10 100 100]+100*cabinAInit,'g');
            
            app.door.left_doorB = fill(app.UIAxes,[2 25.5 25.5 2]+49,[10 10 100 100]+100*cabinBInit,'g');
            app.door.right_doorB = fill(app.UIAxes,[25.5 49 49 25.5]+49,[10 10 100 100]+100*cabinBInit,'g');
            
            app.backGround{1} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0],[120, 87, 85]/255);
            app.backGround{2} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+100,[120, 87, 85]/255);
            app.backGround{3} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+200,[120, 87, 85]/255);
            app.backGround{4} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+300,[120, 87, 85]/255);
            app.backGround{5} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+400,[120, 87, 85]/255);
            app.backGround{6} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+500,[120, 87, 85]/255);
            app.backGround{7} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+600,[120, 87, 85]/255);
            
            app.backGround{8} = fill(app.UIAxes,[49 51 51 49],[0 0 610 610],[120, 87, 85]/255);
            app.backGround{9} = fill(app.UIAxes,[0 2 2 0],[0 0 610 610],[120, 87, 85]/255);
            app.backGround{10} = fill(app.UIAxes,[98 100 100 98],[0 0 610 610],[120, 87, 85]/255);
            
            app.A = app.A.changeProperty('currentDoorPosition',app.door.right_doorA.Vertices(2,2));
            app.A = app.A.changeProperty('currentFloorPosition', app.door.right_doorA.Vertices(2,1));
            app.B = app.B.changeProperty('currentDoorPosition',app.door.right_doorB.Vertices(2,2));
            app.B = app.B.changeProperty('currentFloorPosition', app.door.right_doorB.Vertices(2,1));
            
            app.UIAxes.XLim = [-10 110];
            app.UIAxes.YLim = [-10 660];
            
            % Now we set all color of button to offButtonColor
            app.Down6Button.BackgroundColor = app.offButtonColor;
            app.Down5Button.BackgroundColor = app.offButtonColor;
            app.Down4Button.BackgroundColor = app.offButtonColor;
            app.Down3Button.BackgroundColor = app.offButtonColor;
            app.Down2Button.BackgroundColor = app.offButtonColor;
            
            app.Up5Button.BackgroundColor = app.offButtonColor;
            app.Up4Button.BackgroundColor = app.offButtonColor;
            app.Up3Button.BackgroundColor = app.offButtonColor;
            app.Up2Button.BackgroundColor = app.offButtonColor;
            app.Up1Button.BackgroundColor = app.offButtonColor;
            
            % CabinA
            app.goto1A.BackgroundColor = app.offButtonColor;
            app.goto2A.BackgroundColor = app.offButtonColor;
            app.goto3A.BackgroundColor = app.offButtonColor;
            app.goto4A.BackgroundColor = app.offButtonColor;
            app.goto5A.BackgroundColor = app.offButtonColor;
            app.goto6A.BackgroundColor = app.offButtonColor;
            
            app.goto1B.BackgroundColor = app.offButtonColor;
            app.goto2B.BackgroundColor = app.offButtonColor;
            app.goto3B.BackgroundColor = app.offButtonColor;
            app.goto4B.BackgroundColor = app.offButtonColor;
            app.goto5B.BackgroundColor = app.offButtonColor;
            app.goto6B.BackgroundColor = app.offButtonColor;
            
            % We set all lamp Color to offLampColor
            app.LampFloor1.Color = app.offLampColor;
            app.LampFloor2.Color = app.offLampColor;
            app.LampFloor3.Color = app.offLampColor;
            app.LampFloor4.Color = app.offLampColor;
            app.LampFloor5.Color = app.offLampColor;
            app.LampFloor6.Color = app.offLampColor;
            
            app.doorIsOpeningLampA.Color = app.offLampColor;
            app.doorIsOpeningLampB.Color = app.offLampColor;
            
            start(app.dispTimer)
        end

        % Button pushed function: Down6Button
        function Down6ButtonPushed(app, event)
            if app.Down6Button.BackgroundColor(2) == app.offButtonColor(2) % Meaning in back color
                app.Down6Button.BackgroundColor = app.onButtonColor; % Change to green color
                if ~isempty(app.queue.allQueue)
                    app.queue.allQueue = [app.queue.allQueue; 6];
                    app.queue.allDirection = [app.queue.allDirection;-1];
                else
                    app.queue.allQueue = 6;
                    app.queue.allDirection = -1;
                end
            end
        end

        % Button pushed function: Down5Button
        function Down5ButtonPushed(app, event)
            if app.Down5Button.BackgroundColor(2) == app.offButtonColor(2) % Meaning in back color
                app.Down5Button.BackgroundColor = app.onButtonColor; % Change to green color
                if ~isempty(app.queue.allQueue )
                    app.queue.allQueue = [app.queue.allQueue; 5];
                    app.queue.allDirection = [app.queue.allDirection;-1];
                else
                    app.queue.allQueue = 5;
                    app.queue.allDirection = -1;
                end
            end
        end

        % Button pushed function: Up5Button
        function Up5ButtonPushed(app, event)
            if app.Up5Button.BackgroundColor(2) == app.offButtonColor(2) % Meaning in back color
                app.Up5Button.BackgroundColor = app.onButtonColor; % Change to green color
                if ~isempty(app.queue.allQueue)
                    app.queue.allQueue = [app.queue.allQueue; 5];
                    app.queue.allDirection = [app.queue.allDirection;1];
                else
                    app.queue.allQueue = 5;
                    app.queue.allDirection = 1;
                end
            end
        end

        % Button pushed function: Down4Button
        function Down4ButtonPushed(app, event)
            if app.Down4Button.BackgroundColor(2) == app.offButtonColor(2) % Meaning in back color
                app.Down4Button.BackgroundColor = app.onButtonColor; % Change to green color
                if ~isempty(app.queue.allQueue)
                    app.queue.allQueue = [app.queue.allQueue; 4];
                    app.queue.allDirection = [app.queue.allDirection;-1];
                else
                    app.queue.allQueue = 4;
                    app.queue.allDirection = -1;
                end
            end
        end

        % Button pushed function: Up4Button
        function Up4ButtonPushed(app, event)
            if app.Up4Button.BackgroundColor(2) == app.offButtonColor(2) % Meaning in back color
                app.Up4Button.BackgroundColor = app.onButtonColor; % Change to green color
                if ~isempty(app.queue.allQueue)
                    app.queue.allQueue = [app.queue.allQueue; 4];
                    app.queue.allDirection = [app.queue.allDirection;1];
                else
                    app.queue.allQueue = 4;
                    app.queue.allDirection = 1;
                end
            end
        end

        % Button pushed function: Down3Button
        function Down3ButtonPushed(app, event)
            if app.Down3Button.BackgroundColor(2) == app.offButtonColor(2) % Meaning in back color
                app.Down3Button.BackgroundColor = app.onButtonColor; % Change to green color
                if ~isempty(app.queue.allQueue)
                    app.queue.allQueue = [app.queue.allQueue; 3];
                    app.queue.allDirection = [app.queue.allDirection;-1];
                else
                    app.queue.allQueue = 3;
                    app.queue.allDirection = -1;
                end
                
            end
        end

        % Button pushed function: Up3Button
        function Up3ButtonPushed(app, event)
            if app.Up3Button.BackgroundColor(2) == app.offButtonColor(2) % Meaning in back color
                app.Up3Button.BackgroundColor = app.onButtonColor; % Change to green color
                if ~isempty(app.queue.allQueue)
                    app.queue.allQueue = [app.queue.allQueue; 3];
                    app.queue.allDirection = [app.queue.allDirection;1];
                else
                    app.queue.allQueue = 3;
                    app.queue.allDirection = 1;
                end
            end
        end

        % Button pushed function: Down2Button
        function Down2ButtonPushed(app, event)
            if app.Down2Button.BackgroundColor(2) == app.offButtonColor(2) % Meaning in back color
                app.Down2Button.BackgroundColor = app.onButtonColor; % Change to green color
                if ~isempty(app.queue.allQueue)
                    app.queue.allQueue = [app.queue.allQueue; 2];
                    app.queue.allDirection = [app.queue.allDirection;-1];
                else
                    app.queue.allQueue = 2;
                    app.queue.allDirection = -1;
                end
            end
        end

        % Button pushed function: Up2Button
        function Up2ButtonPushed(app, event)
            if app.Up2Button.BackgroundColor(2) == app.offButtonColor(2) % Meaning in back color
                app.Up2Button.BackgroundColor = app.onButtonColor; % Change to green color
                if ~isempty(app.queue.allQueue)
                    app.queue.allQueue = [app.queue.allQueue; 2];
                    app.queue.allDirection = [app.queue.allDirection;1];
                else
                    app.queue.allQueue = 2;
                    app.queue.allDirection = 1;
                end
            end
        end

        % Button pushed function: Up1Button
        function Up1ButtonPushed(app, event)
            if app.Up1Button.BackgroundColor(2) == app.offButtonColor(2) % Meaning in back color
                app.Up1Button.BackgroundColor = app.onButtonColor; % Change to green color
                if ~isempty(app.queue.allQueue)
                    app.queue.allQueue = [app.queue.allQueue; 1];
                    app.queue.allDirection = [app.queue.allDirection;1];
                else
                    app.queue.allQueue = 1;
                    app.queue.allDirection = 1;
                end
            end
        end

        % Button pushed function: goto1A
        function goto1AButtonPushed(app, event)
            app.goto1A.BackgroundColor = app.onButtonColor;
            if app.A.currentCabinFloor ~= 1
                app.A = app.A.changeProperty('queue',[1 -1]);
            end
        end

        % Button pushed function: goto2A
        function goto2AButtonPushed(app, event)
            app.goto2A.BackgroundColor = app.onButtonColor;
            if app.A.currentCabinFloor >=2
                app.A = app.A.changeProperty('queue',[2 -1]);
            else
                app.A = app.A.changeProperty('queue',[2 1]);
            end
        end

        % Button pushed function: goto3A
        function goto3AButtonPushed(app, event)
            app.goto3A.BackgroundColor = app.onButtonColor;
            if app.A.currentCabinFloor >=3
                app.A = app.A.changeProperty('queue',[3 -1]);
            else
                app.A = app.A.changeProperty('queue',[3 1]);
            end
        end

        % Button pushed function: goto4A
        function goto4AButtonPushed(app, event)
            app.goto4A.BackgroundColor = app.onButtonColor;
            if app.A.currentCabinFloor >=4
                app.A = app.A.changeProperty('queue',[4 -1]);
            else
                app.A = app.A.changeProperty('queue',[4 1]);
            end
        end

        % Button pushed function: goto5A
        function goto5AButtonPushed(app, event)
            app.goto5A.BackgroundColor = app.onButtonColor;
            if app.A.currentCabinFloor >=5
                app.A = app.A.changeProperty('queue',[5 -1]);
            else
                app.A = app.A.changeProperty('queue',[5 1]);
            end
        end

        % Button pushed function: goto6A
        function goto6AButtonPushed(app, event)
            app.goto6A.BackgroundColor = app.onButtonColor;
            if app.A.currentCabinFloor ~= 6
                app.A = app.A.changeProperty('queue',[6 1]);
            end
        end

        % Button pushed function: openDoorA
        function openDoorAButtonPushed(app, event)
            app.openDoorA.BackgroundColor = app.onButtonColor;
            app.A = app.A.changeProperty('doorOpenRequest',true);
        end

        % Button pushed function: closeDoorA
        function closeDoorAButtonPushed(app, event)
            app.closeDoorA.BackgroundColor = app.onButtonColor;
            app.A = app.A.changeProperty('doorCloseRequest',true);
        end

        % Button pushed function: emergencyA
        function emergencyAButtonPushed(app, event)
            if app.emergencyA.BackgroundColor(2) ~= app.onButtonColor(2)
                app.emergencyA.BackgroundColor = app.onButtonColor;
            else
                app.emergencyA.BackgroundColor = app.offButtonColor;
            end
            app.A = app.A.changeProperty('fireAlarm',~app.A.fireAlarm);
        end

        % Button pushed function: goto1B
        function goto1BButtonPushed(app, event)
            app.goto1B.BackgroundColor = app.onButtonColor;
            if app.B.currentCabinFloor ~= 1
                app.B = app.B.changeProperty('queue',[1 -1]);
            end
        end

        % Button pushed function: goto2B
        function goto2BButtonPushed(app, event)
            app.goto2B.BackgroundColor = app.onButtonColor;
            if app.B.currentCabinFloor >=2
                app.B = app.B.changeProperty('queue',[2 -1]);
            else
                app.B = app.B.changeProperty('queue',[2 1]);
            end
        end

        % Button pushed function: goto3B
        function goto3BButtonPushed(app, event)
            app.goto3B.BackgroundColor = app.onButtonColor;
            if app.B.currentCabinFloor >=3
                app.B = app.B.changeProperty('queue',[3 -1]);
            else
                app.B = app.B.changeProperty('queue',[3 1]);
            end
        end

        % Button pushed function: goto4B
        function goto4BButtonPushed(app, event)
            app.goto4B.BackgroundColor = app.onButtonColor;
            if app.B.currentCabinFloor >=4
                app.B = app.B.changeProperty('queue',[4 -1]);
            else
                app.B = app.B.changeProperty('queue',[4 1]);
            end
        end

        % Button pushed function: goto5B
        function goto5BButtonPushed(app, event)
            app.goto5B.BackgroundColor = app.onButtonColor;
            if app.B.currentCabinFloor >=5
                app.B = app.B.changeProperty('queue',[5 -1]);
            else
                app.B = app.B.changeProperty('queue',[5 1]);
            end
        end

        % Button pushed function: goto6B
        function goto6BButtonPushed(app, event)
            app.goto6B.BackgroundColor = app.onButtonColor;
            if app.B.currentCabinFloor ~=6
                app.B = app.B.changeProperty('queue',[6 1]);
            end
        end

        % Button pushed function: openDoorB
        function openDoorBButtonPushed(app, event)
            app.openDoorB.BackgroundColor = app.onButtonColor;
            app.B = app.B.changeProperty('doorOpenRequest',true);
        end

        % Button pushed function: closeDoorB
        function closeDoorBButtonPushed(app, event)
            app.closeDoorB.BackgroundColor = app.onButtonColor;
            app.B = app.B.changeProperty('doorCloseRequest',true);
        end

        % Button pushed function: emergencyB
        function emergencyBButtonPushed(app, event)
            app.emergencyB.BackgroundColor = app.onButtonColor;
            app.B = app.B.changeProperty('fireAlarm',true);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Button to reset elevator to the factory conditions
            % Now we set all color of button to offButtonColor
            app.Down6Button.BackgroundColor = app.offButtonColor;
            app.Down5Button.BackgroundColor = app.offButtonColor;
            app.Down4Button.BackgroundColor = app.offButtonColor;
            app.Down3Button.BackgroundColor = app.offButtonColor;
            app.Down2Button.BackgroundColor = app.offButtonColor;
            
            app.Up5Button.BackgroundColor = app.offButtonColor;
            app.Up4Button.BackgroundColor = app.offButtonColor;
            app.Up3Button.BackgroundColor = app.offButtonColor;
            app.Up2Button.BackgroundColor = app.offButtonColor;
            app.Up1Button.BackgroundColor = app.offButtonColor;
            
            % We set all lamp Color to offLampColor
            app.LampFloor1.Color = app.offLampColor;
            app.LampFloor2.Color = app.offLampColor;
            app.LampFloor3.Color = app.offLampColor;
            app.LampFloor4.Color = app.offLampColor;
            app.LampFloor5.Color = app.offLampColor;
            app.LampFloor6.Color = app.offLampColor;
            
            app.doorIsOpeningLampA.Color = app.offLampColor;
            app.doorIsOpeningLampB.Color = app.offLampColor;
            
            % Reset the queue list
            app.queue.allQueue = [];
            app.queue.allDirection = [];
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Double elevator')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.CabinAPanel = uipanel(app.UIFigure);
            app.CabinAPanel.Title = 'Cabin A';
            app.goto1A = uibutton(app.CabinAPanel, 'push');
            app.goto1A.ButtonPushedFcn = createCallbackFcn(app, @goto1AButtonPushed, true);
            app.goto1A.Text = '1';
            app.goto2A = uibutton(app.CabinAPanel, 'push');
            app.goto2A.ButtonPushedFcn = createCallbackFcn(app, @goto2AButtonPushed, true);
            app.goto2A.Text = '2';
            app.goto4A = uibutton(app.CabinAPanel, 'push');
            app.goto4A.ButtonPushedFcn = createCallbackFcn(app, @goto4AButtonPushed, true);
            app.goto4A.Text = '4';
            app.goto5A = uibutton(app.CabinAPanel, 'push');
            app.goto5A.ButtonPushedFcn = createCallbackFcn(app, @goto5AButtonPushed, true);
            app.goto5A.Text = '5';
            app.closeDoorA = uibutton(app.CabinAPanel, 'push');
            app.closeDoorA.ButtonPushedFcn = createCallbackFcn(app, @closeDoorAButtonPushed, true);
            app.closeDoorA.Text = 'Close door';
            app.goto3A = uibutton(app.CabinAPanel, 'push');
            app.goto3A.ButtonPushedFcn = createCallbackFcn(app, @goto3AButtonPushed, true);
            app.goto3A.Text = '3';
            app.openDoorA = uibutton(app.CabinAPanel, 'push');
            app.openDoorA.ButtonPushedFcn = createCallbackFcn(app, @openDoorAButtonPushed, true);
            app.openDoorA.Text = 'Open door';
            app.emergencyA = uibutton(app.CabinAPanel, 'push');
            app.emergencyA.ButtonPushedFcn = createCallbackFcn(app, @emergencyAButtonPushed, true);
            app.emergencyA.Text = 'Emergency';
            app.goto6A = uibutton(app.CabinAPanel, 'push');
            app.goto6A.ButtonPushedFcn = createCallbackFcn(app, @goto6AButtonPushed, true);
            app.goto6A.Text = '6';
            app.isMovingLampLabel_2 = uilabel(app.CabinAPanel);
            app.isMovingLampLabel_2.Text = 'isMoving';
            app.isMovingLampA = uilamp(app.CabinAPanel);
            app.Floor5Panel = uipanel(app.UIFigure);
            app.Floor5Panel.Title = 'Floor 5';
            app.Down5Button = uibutton(app.Floor5Panel, 'push');
            app.Down5Button.ButtonPushedFcn = createCallbackFcn(app, @Down5ButtonPushed, true);
            app.Down5Button.Text = 'Down5';
            app.LampFloor5 = uilamp(app.Floor5Panel);
            app.Up5Button = uibutton(app.Floor5Panel, 'push');
            app.Up5Button.ButtonPushedFcn = createCallbackFcn(app, @Up5ButtonPushed, true);
            app.Up5Button.Text = 'Up5';
            app.Floor4Panel = uipanel(app.UIFigure);
            app.Floor4Panel.Title = 'Floor 4';
            app.Down4Button = uibutton(app.Floor4Panel, 'push');
            app.Down4Button.ButtonPushedFcn = createCallbackFcn(app, @Down4ButtonPushed, true);
            app.Down4Button.Text = 'Down4';
            app.Up4Button = uibutton(app.Floor4Panel, 'push');
            app.Up4Button.ButtonPushedFcn = createCallbackFcn(app, @Up4ButtonPushed, true);
            app.Up4Button.Text = 'Up4';
            app.LampFloor4 = uilamp(app.Floor4Panel);
            app.Floor2Panel = uipanel(app.UIFigure);
            app.Floor2Panel.Title = 'Floor 2';
            app.Down2Button = uibutton(app.Floor2Panel, 'push');
            app.Down2Button.ButtonPushedFcn = createCallbackFcn(app, @Down2ButtonPushed, true);
            app.Down2Button.Text = 'Down2';
            app.Up2Button = uibutton(app.Floor2Panel, 'push');
            app.Up2Button.ButtonPushedFcn = createCallbackFcn(app, @Up2ButtonPushed, true);
            app.Up2Button.Text = 'Up2';
            app.LampFloor2 = uilamp(app.Floor2Panel);
            app.Floor1Panel = uipanel(app.UIFigure);
            app.Floor1Panel.Title = 'Floor 1';
            app.Up1Button = uibutton(app.Floor1Panel, 'push');
            app.Up1Button.ButtonPushedFcn = createCallbackFcn(app, @Up1ButtonPushed, true);
            app.Up1Button.Text = 'Up1';
            app.LampFloor1 = uilamp(app.Floor1Panel);
            app.Floor3Panel = uipanel(app.UIFigure);
            app.Floor3Panel.Title = 'Floor 3';
            app.Down3Button = uibutton(app.Floor3Panel, 'push');
            app.Down3Button.ButtonPushedFcn = createCallbackFcn(app, @Down3ButtonPushed, true);
            app.Down3Button.Text = 'Down3';
            app.Up3Button = uibutton(app.Floor3Panel, 'push');
            app.Up3Button.ButtonPushedFcn = createCallbackFcn(app, @Up3ButtonPushed, true);
            app.Up3Button.Text = 'Up3';
            app.LampFloor3 = uilamp(app.Floor3Panel);
            app.elevatorIndicatorPanel = uipanel(app.UIFigure);
            app.elevatorIndicatorPanel.Title = 'elevator Indicator';
            app.DoorstateEditFieldLabel = uilabel(app.elevatorIndicatorPanel);
            app.DoorstateEditFieldLabel.Text = 'Door state';
            app.DoorStateEditField = uieditfield(app.elevatorIndicatorPanel, 'text');
            app.ElevatorstateEditFieldLabel = uilabel(app.elevatorIndicatorPanel);
            app.ElevatorstateEditFieldLabel.Text = 'Elevator state';
            app.ElevatorStateEditField = uieditfield(app.elevatorIndicatorPanel, 'text');
            app.ListcommandEditFieldLabel = uilabel(app.elevatorIndicatorPanel);
            app.ListcommandEditFieldLabel.Text = 'List command';
            app.ListCommandEditField = uieditfield(app.elevatorIndicatorPanel, 'text');
            app.CurrentdirectionEditFieldLabel = uilabel(app.elevatorIndicatorPanel);
            app.CurrentdirectionEditFieldLabel.Text = 'Current direction';
            app.CurrentDirectionEditField = uieditfield(app.elevatorIndicatorPanel, 'text');
            app.ResetButton = uibutton(app.elevatorIndicatorPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CabinBPanel = uipanel(app.UIFigure);
            app.CabinBPanel.Title = 'Cabin B';
            app.goto1B = uibutton(app.CabinBPanel, 'push');
            app.goto1B.ButtonPushedFcn = createCallbackFcn(app, @goto1BButtonPushed, true);
            app.goto1B.Text = '1';
            app.goto2B = uibutton(app.CabinBPanel, 'push');
            app.goto2B.ButtonPushedFcn = createCallbackFcn(app, @goto2BButtonPushed, true);
            app.goto2B.Text = '2';
            app.goto4B = uibutton(app.CabinBPanel, 'push');
            app.goto4B.ButtonPushedFcn = createCallbackFcn(app, @goto4BButtonPushed, true);
            app.goto4B.Text = '4';
            app.goto5B = uibutton(app.CabinBPanel, 'push');
            app.goto5B.ButtonPushedFcn = createCallbackFcn(app, @goto5BButtonPushed, true);
            app.goto5B.Text = '5';
            app.closeDoorB = uibutton(app.CabinBPanel, 'push');
            app.closeDoorB.ButtonPushedFcn = createCallbackFcn(app, @closeDoorBButtonPushed, true);
            app.closeDoorB.Text = 'Close door';
            app.goto3B = uibutton(app.CabinBPanel, 'push');
            app.goto3B.ButtonPushedFcn = createCallbackFcn(app, @goto3BButtonPushed, true);
            app.goto3B.Text = '3';
            app.openDoorB = uibutton(app.CabinBPanel, 'push');
            app.openDoorB.ButtonPushedFcn = createCallbackFcn(app, @openDoorBButtonPushed, true);
            app.openDoorB.Text = 'Open door';
            app.emergencyB = uibutton(app.CabinBPanel, 'push');
            app.emergencyB.ButtonPushedFcn = createCallbackFcn(app, @emergencyBButtonPushed, true);
            app.emergencyB.Text = 'Emergency';
            app.goto6B = uibutton(app.CabinBPanel, 'push');
            app.goto6B.ButtonPushedFcn = createCallbackFcn(app, @goto6BButtonPushed, true);
            app.goto6B.Text = '6';
            app.isMovingLampLabel = uilabel(app.CabinBPanel);
            app.isMovingLampLabel.Text = 'isMoving';
            app.isMovingLampB = uilamp(app.CabinBPanel);
            app.Floor6Panel = uipanel(app.UIFigure);
            app.Floor6Panel.Title = 'Floor 6';
            app.Down6Button = uibutton(app.Floor6Panel, 'push');
            app.Down6Button.ButtonPushedFcn = createCallbackFcn(app, @Down6ButtonPushed, true);
            app.Down6Button.Text = 'Down6';
            app.LampFloor6 = uilamp(app.Floor6Panel);
            app.CabinIndicatorPanel = uipanel(app.UIFigure);
            app.CabinIndicatorPanel.Title = 'Cabin Indicator';
            app.doorALabel = uilabel(app.CabinIndicatorPanel);
            app.doorALabel.Text = 'door A';
            app.doorIsOpeningLampA = uilamp(app.CabinIndicatorPanel);
            app.doorBLabel = uilabel(app.CabinIndicatorPanel);
            app.doorBLabel.Text = 'door B';
            app.doorIsOpeningLampB = uilamp(app.CabinIndicatorPanel);
            app.TimeAEditFieldLabel = uilabel(app.CabinIndicatorPanel);
            app.TimeAEditFieldLabel.Text = 'TimeA';
            app.TimeAEditField = uieditfield(app.CabinIndicatorPanel, 'text');
            app.TimeBEditFieldLabel = uilabel(app.CabinIndicatorPanel);
            app.TimeBEditFieldLabel.Text = 'TimeB';
            app.TimeBEditField = uieditfield(app.CabinIndicatorPanel, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = double_elevator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end