classdef elevator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElevatorUIFigure             matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        Floor4Panel                  matlab.ui.container.Panel
        Down4                        matlab.ui.control.Button
        LampFloor4                   matlab.ui.control.Lamp
        Floor3Panel                  matlab.ui.container.Panel
        Down3                        matlab.ui.control.Button
        Up3                          matlab.ui.control.Button
        LampFloor3                   matlab.ui.control.Lamp
        Floor2Panel                  matlab.ui.container.Panel
        Down2                        matlab.ui.control.Button
        Up2                          matlab.ui.control.Button
        LampFloor2                   matlab.ui.control.Lamp
        Floor1Panel                  matlab.ui.container.Panel
        Up1                          matlab.ui.control.Button
        LampFloor1                   matlab.ui.control.Lamp
        DoorKeepOpenEditFieldLabel   matlab.ui.control.Label
        DoorKeepOpenEditField        matlab.ui.control.NumericEditField
        ElevatorStateEditFieldLabel  matlab.ui.control.Label
        ElevatorStateEditField       matlab.ui.control.EditField
        DoorEditFieldLabel           matlab.ui.control.Label
        DoorEditField                matlab.ui.control.EditField
        CabinPanel                   matlab.ui.container.Panel
        OpenDoor                     matlab.ui.control.Button
        CloseDoor                    matlab.ui.control.Button
        isMovingLampLabel            matlab.ui.control.Label
        isMovingLamp                 matlab.ui.control.Lamp
        Goto1Button                  matlab.ui.control.Button
        Goto2Button                  matlab.ui.control.Button
        Goto3Button                  matlab.ui.control.Button
        Goto4Button                  matlab.ui.control.Button
        EmegencyButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        dispTimer % Use this timer to display the evevator
        data
        door
        floor
        
    end
    
    properties (Access = private)
        % State for the elevator: idle down up and door
        % Idle is the normal case when the system is on idle state from which it can
        % go to door open or going up/down. from up/down need to go through idle state.
        
        % The current state of the elevator
        elevatorState
        currentDirection
        currentFloor
        queue
        elevatorIsMoving
        
        % Cabin condition
        currentCabinPosition
        nextLowerCabinPosition
        nextUpperCabinPosition
        
        % In the dooropen, must be in doorOpening State to exercute
        % Door has two state
        doorState = 'canOpen' % canClose % canKeepingOpen

        % And door has three transition
        doorOpening = false
        doorKeepingOpen = false
        doorClosing = false
        

    end
    
    methods (Access = public)
        
        function moveElevator(app,~,~)
            velXdoor = [0 0 1 1 0 -1 -1 0 0 -1;0 0 0 0 0 0 0 0 0 0]';
            velYdoor = 5*[0 0 0 0 0 0 0 0 0 0;1 1 1 1 1, 1 1 1 1 1]';
            dt = 1;
            
            % Check current position
            floorPosition = [0 110 220 330];

            switch(app.elevatorState)
                case 'idle'
                    % If the elevator goes to idle state, then door can be opened or the cabin can move now
                    app.currentCabinPosition = app.door.Vertices(1,2);
                    [~,ind] = max(app.currentCabinPosition==floorPosition);
                    switch(ind)
                        case 1
                            % Change color of the lamp first
                            app.LampFloor1.Color = 'g';
                            app.LampFloor2.Color = 'r';
                            app.LampFloor3.Color = 'r';
                            app.LampFloor4.Color = 'r';
                        case 2
                            % Change color of the lamp first
                            app.LampFloor1.Color = 'r';
                            app.LampFloor2.Color = 'g';
                            app.LampFloor3.Color = 'r';
                            app.LampFloor4.Color = 'r';
                        case 3
                            % Change color of the lamp first
                            app.LampFloor1.Color = 'r';
                            app.LampFloor2.Color = 'r';
                            app.LampFloor3.Color = 'g';
                            app.LampFloor4.Color = 'r';
                        case 4
                            % Change color of the lamp first
                            app.LampFloor1.Color = 'r';
                            app.LampFloor2.Color = 'r';
                            app.LampFloor3.Color = 'r';
                            app.LampFloor4.Color = 'g';
                    end
                    if app.doorOpening % If door is opening we need to keep opening
                        app.elevatorState = 'door';
                    else
                        if ~isempty(app.queue.floor)
                            % If there is a request in the queue, we will execute
                            switch(ind)
                                case 1
                                    % We are in the first floor
                                    
                                    
                                    if app.queue.floor(1) ~= 1 % 2,3,4 go up
                                        app.elevatorState = 'up';
                                        app.elevatorIsMoving = true;
                                        app.currentDirection = 'up';
                                    end
                                case 2
                                    % We are in the second floor
                                    
                                    if app.queue.floor(1) == 1 % in 2 next 1 go down
                                        app.elevatorState = 'down';
                                        app.elevatorIsMoving = true;
                                        app.currentDirection = 'down';
                                    elseif app.queue.floor(1)>2 % in2 next 3,4 go up
                                        app.elevatorState = 'up';
                                        app.elevatorIsMoving = true;
                                        app.currentDirection = 'up';
                                    end
                                case 3
                                    % We are in the third floor
                                    
                                    if app.queue.floor(1) < 3 % in 3 next 1,2 go down 
                                        app.elevatorState = 'down';
                                        app.elevatorIsMoving = true;
                                        app.currentDirection = 'down';
                                    elseif app.queue.floor(1)> 3 % in 3 next 4 go up
                                        app.elevatorState = 'up';
                                        app.elevatorIsMoving = true;
                                        app.currentDirection = 'up';
                                    end
                                case 4
                                    % We are in the fourth floor
                                    
                                    if app.queue.floor(1) ~= 4
                                        app.elevatorState = 'down';
                                        app.elevatorIsMoving = true;
                                        app.currentDirection = 'down';
                                    end
                            end
                        else
                            app.elevatorIsMoving = false;
                        end
                    end
                    
                    
                case 'down'
                    % Get the current cabin position
                    app.currentCabinPosition = app.door.Vertices(1,2);
                    ind = sum(app.currentCabinPosition>=floorPosition);
                    switch(ind)
                        case 1
                            app.LampFloor1.Color = 'g';
                            app.LampFloor2.Color = 'r';
                            app.LampFloor3.Color = 'r';
                            app.LampFloor4.Color = 'r';
                        case 2
                            app.LampFloor1.Color = 'r';
                            app.LampFloor2.Color = 'g';
                            app.LampFloor3.Color = 'r';
                            app.LampFloor4.Color = 'r';
                        case 3
                            app.LampFloor1.Color = 'r';
                            app.LampFloor2.Color = 'r';
                            app.LampFloor3.Color = 'g';
                            app.LampFloor4.Color = 'r';
                        case 4
                            app.LampFloor1.Color = 'r';
                            app.LampFloor2.Color = 'r';
                            app.LampFloor3.Color = 'r';
                            app.LampFloor4.Color = 'g';
                    end
                    
                    
                    app.nextLowerCabinPosition = app.queue.floor(1)*110-110;
                    if app.door.Vertices(1,2)>app.nextLowerCabinPosition
                        app.isMovingLamp.Color = 'g';
                        set(app.door, 'Vertices',app.door.Vertices - velYdoor*dt); % - mean go down
                        app.DoorKeepOpenEditField.Value = app.door.Vertices(1,2);
                    else
                        app.DoorKeepOpenEditField.Value = app.door.Vertices(1,2);
                        app.elevatorState = 'idle';
                        app.elevatorState = 'door';
                        app.isMovingLamp.Color = 'r';
                        app.elevatorIsMoving = false;
                        % Now we delete the current function
                        floorTemp = app.queue.floor;
                        directionTemp = app.queue.direction;
                        if length(floorTemp) == 1
                            app.queue.floor = [];
                            app.queue.direction = [];
                        else
%                             app.queue.floor = [];
%                             app.queue.direction = [];
                            app.queue.floor = floorTemp(2:end);
                            app.queue.direction = directionTemp(2:end);
                        end
                    end


                case 'up'
                    % Get the current cabin position
                    app.currentCabinPosition = app.door.Vertices(1,2);
                    ind = sum(app.currentCabinPosition>=floorPosition);
                    switch(ind)
                        case 1
                            app.LampFloor1.Color = 'g';
                            app.LampFloor2.Color = 'r';
                            app.LampFloor3.Color = 'r';
                            app.LampFloor4.Color = 'r';
                        case 2
                            app.LampFloor1.Color = 'r';
                            app.LampFloor2.Color = 'g';
                            app.LampFloor3.Color = 'r';
                            app.LampFloor4.Color = 'r';
                        case 3
                            app.LampFloor1.Color = 'r';
                            app.LampFloor2.Color = 'r';
                            app.LampFloor3.Color = 'g';
                            app.LampFloor4.Color = 'r';
                        case 4
                            app.LampFloor1.Color = 'r';
                            app.LampFloor2.Color = 'r';
                            app.LampFloor3.Color = 'r';
                            app.LampFloor4.Color = 'g';
                    end
                    
                    app.nextUpperCabinPosition = app.queue.floor(1)*110-110;
                    if app.door.Vertices(1,2) < app.nextUpperCabinPosition
                        app.isMovingLamp.Color = 'g';
                        set(app.door, 'Vertices',app.door.Vertices + velYdoor*dt); % + means go up
                        app.DoorKeepOpenEditField.Value = app.door.Vertices(1,2);
                    else
                        app.DoorKeepOpenEditField.Value = app.door.Vertices(1,2);
                        app.elevatorState = 'idle';
                        app.elevatorState = 'door';
                        app.isMovingLamp.Color = 'r';
                        app.elevatorIsMoving = false;
                        % Now we delete the current function
                        floorTemp = app.queue.floor;
                        directionTemp = app.queue.direction;
                        if length(floorTemp) == 1
                            app.queue.floor = [];
                            app.queue.direction = [];
                        else
%                             app.queue.floor = [];
%                             app.queue.direction = [];
                            app.queue.floor = floorTemp(2:end);
                            app.queue.direction = directionTemp(2:end);
                            
                        end
                    end
                    
                    
                case 'door'
                    app.isMovingLamp.Color = 'r';
                    app.doorOpening = true;
                    switch(app.doorState)
                        case 'canOpen'
                            if app.door.Vertices(3,1) >= 5 % The door is completely opened
                                set(app.door, 'Vertices',app.door.Vertices-velXdoor*dt);
                            else
                                app.doorState = 'canKeepingOpen';
                            end
                        case 'canClose'
                            if app.door.Vertices(3,1) < 50 % The door is completely closed
                                set(app.door, 'Vertices',app.door.Vertices+velXdoor*dt);
                            else
                                app.doorState = 'canOpen';
                                app.elevatorState = 'idle';
                                app.doorOpening = false;
                            end
                        case 'canKeepingOpen'
                            app.data.keepDoor = app.data.keepDoor+1;
                            app.DoorKeepOpenEditField.Value = app.data.keepDoor*app.dispTimer.Period;
                            if app.data.keepDoor == app.data.keepMaxDoor
                                app.data.keepDoor = 0;
                                app.doorState = 'canClose';
                                
                            end
                    end
                    
                    % Show the current door state
                    app.DoorEditField.Value = app.doorState;
                case 'emegency'
                    if sum(app.LampFloor1.Color==[1 0 0])==3 % Change to black color and back to red
                        app.LampFloor1.Color = 'k';
                        app.LampFloor2.Color = 'k';
                        app.LampFloor3.Color = 'k';
                        app.LampFloor4.Color = 'k';
                        app.isMovingLamp.Color = 'k';
                    else
                        app.LampFloor1.Color = 'r';
                        app.LampFloor2.Color = 'r';
                        app.LampFloor3.Color = 'r';
                        app.LampFloor4.Color = 'r';
                        app.isMovingLamp.Color = 'r';
                    end
                    
            end
            
            % Show the current state of the elevator
            app.ElevatorStateEditField.Value = app.elevatorState;
                 

        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Delete the previous timer
            delete(timerfind)
            
            % Create a new timer for new run
            app.dispTimer = timer('Period',0.03,...
                'ExecutionMode', 'fixedRate', ...
                'TasksToExecute', Inf);
            app.dispTimer.TimerFcn = {@app.moveElevator};
            
            plot(app.UIAxes,[1 2 3 4],'-r');
            hold(app.UIAxes);
            plot(app.UIAxes,[10 9 4 7],'--b');

            Xfloor = [-10 -10 110 110 -10,...
                -10 -10 110 110 -10,...
                -10 -10 110 110 -10,...
                -10 -10 110 110 -10,...
                -10 -10 110 110 -10];
            Yfloor = [-10 0 0 -10 -10,...
                100 110 110 100 100,...
                210 220 220 210 210,...
                320 330 330 320 320,...
                430 440 440 430 430];
            
            
            % floor = fill(Xfloor,Yfloor,'r');
            
            Xdoor = [0 0 50 50 0,...
                50 50 100 100 50];
            Ydoor = [0 100 100 0 0,...
                0 100 100 0 0];
            
            app.door = fill(app.UIAxes,Xdoor,Ydoor,'g');
            app.floor = fill(app.UIAxes,Xfloor,Yfloor,'r');
            
            
            % Init the condition of the elevator: door closed and elevator at floor 1
            app.elevatorState = 'idle';
            app.doorState = 'canOpen';
            app.currentDirection = 'up';
            app.currentFloor = 1; % currentFloor:1 2 3 4
            app.elevatorIsMoving = false;
            
            % Init the door condition
            app.data.keepDoor = 0;
            app.data.keepMaxDoor = 50;% keep for period x 150, for instance 0.03 x 150 (s)
            app.ElevatorStateEditField.Value = app.elevatorState;
            app.DoorEditField.Value = app.doorState;
            
            % Init the queue
            app.queue.direction = [];
            app.queue.floor = [];
            app.currentCabinPosition = app.door.Vertices(1,2);
            app.nextLowerCabinPosition = 0;
            app.nextUpperCabinPosition = 0;
            
            % Set Lamp
            app.LampFloor1.Color = 'r';
            app.LampFloor2.Color = 'r';
            app.LampFloor3.Color = 'r';
            app.LampFloor4.Color = 'r';
            app.isMovingLamp.Color = 'r';


            start(app.dispTimer)
        end

        % Button pushed function: OpenDoor
        function OpenDoorButtonPushed(app, event)
            app.data.openDoor = true;
            if strcmp(app.elevatorState,'idle') && ~app.elevatorIsMoving
                app.elevatorState = 'door';
            elseif strcmp(app.elevatorState,'door') && strcmp(app.doorState,'canClose')
                app.doorState = 'canOpen';
            end
        end

        % Button pushed function: CloseDoor
        function CloseDoorButtonPushed(app, event)
            if strcmp(app.doorState,'canKeepingOpen')
                app.doorState = 'canClose';
                app.data.keepDoor = 0;
            end
        end

        % Button pushed function: Down4
        function Down4ButtonPushed(app, event)
            % Get the current position of the cabin
            app.currentCabinPosition = app.door.Vertices(1,2);
            
            if isempty(app.queue.floor) && app.currentCabinPosition~=330
                app.queue.direction = [app.queue.direction; -1];
                app.queue.floor = [app.queue.floor;4];
            end
        end

        % Button pushed function: Down3
        function Down3ButtonPushed(app, event)
            % Get the current position of the cabin
            app.currentCabinPosition = app.door.Vertices(1,2);
            
            if isempty(app.queue.floor) && app.currentCabinPosition~=220
                app.queue.direction = [app.queue.direction; -1];
                app.queue.floor = [app.queue.floor;3];
            end
        end

        % Button pushed function: Up3
        function Up3ButtonPushed(app, event)
            % Get the current position of the cabin
            app.currentCabinPosition = app.door.Vertices(1,2);
            
            if isempty(app.queue.floor) && app.currentCabinPosition~=220
                app.queue.direction = [app.queue.direction; 1];
                app.queue.floor = [app.queue.floor;3];
            end
        end

        % Button pushed function: Down2
        function Down2ButtonPushed(app, event)
            % Get the current position of the cabin
            app.currentCabinPosition = app.door.Vertices(1,2);
            
            if isempty(app.queue.floor) && app.currentCabinPosition~=110
                app.queue.direction = [app.queue.direction; -1];
                app.queue.floor = [app.queue.floor;2];
            end
        end

        % Button pushed function: Up2
        function Up2ButtonPushed(app, event)
            % Get the current position of the cabin
            app.currentCabinPosition = app.door.Vertices(1,2);
            
            if isempty(app.queue.floor) && app.currentCabinPosition~=110
                app.queue.direction = [app.queue.direction; 1];
                app.queue.floor = [app.queue.floor;2];
            end
        end

        % Button pushed function: Up1
        function Up1ButtonPushed(app, event)
            % Get the current position of the cabin
            app.currentCabinPosition = app.door.Vertices(1,2);
            
            if isempty(app.queue.floor) && app.currentCabinPosition~=0
                app.queue.direction = [app.queue.direction; 1];
                app.queue.floor = [app.queue.floor;1];
            end
        end

        % Button pushed function: Goto1Button
        function Goto1ButtonPushed(app, event)
            % Get the current position of the cabin
            app.currentCabinPosition = app.door.Vertices(1,2);
            
            if isempty(app.queue.floor) && app.currentCabinPosition ~= 0
                app.queue.direction = [app.queue.direction; -1];
                app.queue.floor = [app.queue.floor;1];
            end
            
%             if app.currentCabinPosition ~= 0
%                 app.nextLowerCabinPosition = 0;
%                 app.nextUpperCabinPosition = round(app.currentCabinPosition/110)+1;
%                 
%             end
                
        end

        % Button pushed function: Goto2Button
        function Goto2ButtonPushed(app, event)
            % Get the current position of the cabin
            app.currentCabinPosition = app.door.Vertices(1,2);
            
            if app.currentCabinPosition == 110
                
            elseif app.currentCabinPosition < 110 && isempty(app.queue.floor)
                app.queue.direction = [app.queue.direction; 1];
                app.queue.floor = [app.queue.floor;2];
%                 app.nextLowerCabinPosition = 0;
%                 app.nextUpperCabinPosition = 110;
            elseif app.currentCabinPosition > 110 && isempty(app.queue.floor)
                app.queue.direction = [app.queue.direction; -1];
                app.queue.floor = [app.queue.floor;2];
%                 app.nextLowerCabinPosition = 110;
%                 app.nextUpperCabinPosition = round(app.currentCabinPosition/110)+1;
            end
        end

        % Button pushed function: Goto3Button
        function Goto3ButtonPushed(app, event)
            % Get the current position of the cabin
            app.currentCabinPosition = app.door.Vertices(1,2);
            
            if app.currentCabinPosition == 220
                
            elseif app.currentCabinPosition < 220 && isempty(app.queue.floor)
                app.queue.direction = [app.queue.direction; 1];
                app.queue.floor = [app.queue.floor;3];
%                 app.nextLowerCabinPosition = 0;
%                 app.nextUpperCabinPosition = 220;
            elseif app.currentCabinPosition > 220 && isempty(app.queue.floor)
                app.queue.direction = [app.queue.direction; -1];
                app.queue.floor = [app.queue.floor;3];
%                 app.nextLowerCabinPosition = 220;
%                 app.nextUpperCabinPosition = round(app.currentCabinPosition/110)+1;
            end
        end

        % Button pushed function: Goto4Button
        function Goto4ButtonPushed(app, event)
            % Get the current position of the cabin
            app.currentCabinPosition = app.door.Vertices(1,2);
            
            if app.currentCabinPosition ~= 330 && isempty(app.queue.floor)
                app.queue.direction = [app.queue.direction; 1];
                app.queue.floor = [app.queue.floor;4];
                app.nextLowerCabinPosition = round(app.currentCabinPosition/110)-1;
                app.nextUpperCabinPosition = 330;
            end
        end

        % Button pushed function: EmegencyButton
        function EmegencyButtonPushed(app, event)
            if ~strcmp(app.elevatorState,'emegency')
                app.elevatorState = 'emegency';
                app.LampFloor1.Color = 'r';
                app.LampFloor2.Color = 'r';
                app.LampFloor3.Color = 'r';
                app.LampFloor4.Color = 'r';
                app.isMovingLamp.Color = 'r';
            elseif strcmp(app.elevatorState,'emegency') && ~app.elevatorIsMoving
                app.elevatorState = 'idle';
            elseif strcmp(app.elevatorState,'emegency') && app.elevatorIsMoving
                app.elevatorState = 'idle';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElevatorUIFigure = uifigure;
            app.ElevatorUIFigure.Name = 'Elevator';
            app.UIAxes = uiaxes(app.ElevatorUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.Floor4Panel = uipanel(app.ElevatorUIFigure);
            app.Floor4Panel.Title = 'Floor4';
            app.Down4 = uibutton(app.Floor4Panel, 'push');
            app.Down4.ButtonPushedFcn = createCallbackFcn(app, @Down4ButtonPushed, true);
            app.Down4.Text = 'Down';
            app.LampFloor4 = uilamp(app.Floor4Panel);
            app.Floor3Panel = uipanel(app.ElevatorUIFigure);
            app.Floor3Panel.Title = 'Floor3';
            app.Down3 = uibutton(app.Floor3Panel, 'push');
            app.Down3.ButtonPushedFcn = createCallbackFcn(app, @Down3ButtonPushed, true);
            app.Down3.Text = 'Down';
            app.Up3 = uibutton(app.Floor3Panel, 'push');
            app.Up3.ButtonPushedFcn = createCallbackFcn(app, @Up3ButtonPushed, true);
            app.Up3.Text = 'Up';
            app.LampFloor3 = uilamp(app.Floor3Panel);
            app.Floor2Panel = uipanel(app.ElevatorUIFigure);
            app.Floor2Panel.Title = 'Floor2';
            app.Down2 = uibutton(app.Floor2Panel, 'push');
            app.Down2.ButtonPushedFcn = createCallbackFcn(app, @Down2ButtonPushed, true);
            app.Down2.Text = 'Down';
            app.Up2 = uibutton(app.Floor2Panel, 'push');
            app.Up2.ButtonPushedFcn = createCallbackFcn(app, @Up2ButtonPushed, true);
            app.Up2.Text = 'Up';
            app.LampFloor2 = uilamp(app.Floor2Panel);
            app.Floor1Panel = uipanel(app.ElevatorUIFigure);
            app.Floor1Panel.Title = 'Floor1';
            app.Up1 = uibutton(app.Floor1Panel, 'push');
            app.Up1.ButtonPushedFcn = createCallbackFcn(app, @Up1ButtonPushed, true);
            app.Up1.Text = 'Up';
            app.LampFloor1 = uilamp(app.Floor1Panel);
            app.DoorKeepOpenEditFieldLabel = uilabel(app.ElevatorUIFigure);
            app.DoorKeepOpenEditFieldLabel.Text = 'Door Keep Open';
            app.DoorKeepOpenEditField = uieditfield(app.ElevatorUIFigure, 'numeric');
            app.ElevatorStateEditFieldLabel = uilabel(app.ElevatorUIFigure);
            app.ElevatorStateEditFieldLabel.Text = 'ElevatorState';
            app.ElevatorStateEditField = uieditfield(app.ElevatorUIFigure, 'text');
            app.DoorEditFieldLabel = uilabel(app.ElevatorUIFigure);
            app.DoorEditFieldLabel.Text = 'Door';
            app.DoorEditField = uieditfield(app.ElevatorUIFigure, 'text');
            app.CabinPanel = uipanel(app.ElevatorUIFigure);
            app.CabinPanel.Title = 'Cabin';
            app.OpenDoor = uibutton(app.CabinPanel, 'push');
            app.OpenDoor.ButtonPushedFcn = createCallbackFcn(app, @OpenDoorButtonPushed, true);
            app.OpenDoor.Text = 'OpenDoor';
            app.CloseDoor = uibutton(app.CabinPanel, 'push');
            app.CloseDoor.ButtonPushedFcn = createCallbackFcn(app, @CloseDoorButtonPushed, true);
            app.CloseDoor.Text = 'CloseDoor';
            app.isMovingLampLabel = uilabel(app.CabinPanel);
            app.isMovingLampLabel.Text = 'isMoving';
            app.isMovingLamp = uilamp(app.CabinPanel);
            app.Goto1Button = uibutton(app.CabinPanel, 'push');
            app.Goto1Button.ButtonPushedFcn = createCallbackFcn(app, @Goto1ButtonPushed, true);
            app.Goto1Button.Text = 'Goto 1';
            app.Goto2Button = uibutton(app.CabinPanel, 'push');
            app.Goto2Button.ButtonPushedFcn = createCallbackFcn(app, @Goto2ButtonPushed, true);
            app.Goto2Button.Text = 'Goto 2';
            app.Goto3Button = uibutton(app.CabinPanel, 'push');
            app.Goto3Button.ButtonPushedFcn = createCallbackFcn(app, @Goto3ButtonPushed, true);
            app.Goto3Button.Text = 'Goto 3';
            app.Goto4Button = uibutton(app.CabinPanel, 'push');
            app.Goto4Button.ButtonPushedFcn = createCallbackFcn(app, @Goto4ButtonPushed, true);
            app.Goto4Button.Text = 'Goto 4';
            app.EmegencyButton = uibutton(app.CabinPanel, 'push');
            app.EmegencyButton.ButtonPushedFcn = createCallbackFcn(app, @EmegencyButtonPushed, true);
            app.EmegencyButton.Text = 'Emegency';
        end
    end

    methods (Access = public)

        % Construct app
        function app = elevator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElevatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElevatorUIFigure)
        end
    end
end